classdef P3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        LengthofTrackEditFieldLabel   matlab.ui.control.Label
        LengthofTrackEditField        matlab.ui.control.NumericEditField
        GeneratetruetrackblueButton   matlab.ui.control.Button
        GeneratemeasurementredButton  matlab.ui.control.Button
        KalmanfiltergreenButton       matlab.ui.control.Button
        ClearfigureButton             matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UITable_2                     matlab.ui.control.Table
        ErrorinvelocityLabel          matlab.ui.control.Label
        ErrorinpositionLabel          matlab.ui.control.Label
    end

    properties (Access = private)
        Length % Length of track
        
        HTrack % Handle to track plot
        HMeasurements % Handle to measurements plot
        HKalman % Handle to Kalman filter plot
                
        Track % Trackpoints
        Measurements % Measurement points
        Kalman % Kalman filter points
        
        Error % [[Measurements] [Kalman]]
    end

    methods (Access = private)
    
        function updateErrors(app)
            app.UITable.Data =[{'rms'; 'max'; 'median'} num2cell(app.Error(1:3,1:2))];
            app.UITable_2.Data =[{'rms'; 'max'; 'median'} num2cell(app.Error(1:3,3:4))];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Setup(app)
            clearFigure(app);
        end

        % Button pushed function: GeneratetruetrackblueButton
        function truePushed(app, event)
            clearFigure(app);
            x0 = zeros(4,1); % Mean of initial state
            Phi = [eye(2) eye(2);zeros(2,2) eye(2)];% State transition matrix
            Q = [1/3*eye(2),1/2*eye(2);1/2*eye(2),eye(2)]; % Covariance matrix of state model
            app.Length = app.LengthofTrackEditField.Value; % length of track
            dim = 2+2;% Dimension 2D position and 2D velocity
            app.Track = nan(dim,app.Length+1); % 0 ... Length of track
            app.Track(:,1) = x0;

            for t=1:app.Length
                app.Track(:,t+1)=Phi*app.Track(:,t)+mvnrnd(zeros(1,4),Q)';
            end
            
            hold(app.UIAxes);
            app.HTrack = plot(app.UIAxes, app.Track(1,:), app.Track(2,:), 'b.-');
            hold(app.UIAxes);
        end

        % Button pushed function: ClearfigureButton
        function clearFigure(app, event)
            cla(app.UIAxes);
            app.Error = zeros(3,4); % TODO tähän ei mahdu nopeudet ei tarvii lol (oikeesti tarvii)
            updateErrors(app);
        end

        % Button pushed function: GeneratemeasurementredButton
        function measurementPushed(app, event)
            if ishandle(app.HTrack)

                H = [eye(2) zeros(2,2)];
                sigmax2 = 4^2;
                sigmay2 = 2^2;
                R = diag([sigmax2,sigmay2]);
                P0 = diag([100 100 1 1]);% Covariance matrix of initial state
                Phi = [eye(2) eye(2);zeros(2,2) eye(2)];% State transition matrix
                x0 = zeros(4,1); % Mean of initial state
                Q = [1/3*eye(2),1/2*eye(2);1/2*eye(2),eye(2)]; % Covariance matrix of state model
                dim = 2+2;% Dimension 2D position and 2D velocity
                app.Measurements = nan(2,app.Length);
                app.Kalman = nan(size(app.Track));
                app.Kalman(:,1)=x0;
                P = nan(dim,dim,app.Length+1);
                P(:,:,1) = P0;
    
                % Calculates the simulated path based on "original" path
                for t=1:app.Length
                    app.Measurements(:,t)=H*app.Track(:,t+1)+mvnrnd(zeros(1,2),R)';
                end
                
                % Difference between trackpoints and measured values
                err = sqrt(sum((app.Track(1:2,2:end)-app.Measurements).^2));
                
                % Measurements' rms error in location
                app.Error(1,1) = sqrt(immse(app.Track(1:2,2:end), app.Measurements));
                % Measurements' max error in location
                app.Error(2,1) = max(max(err));
                % Measurements' median of error in location
                app.Error(3,1) = median(err);
                updateErrors(app);
                
                % The Kalman figure datapoints are calculated but not drawn as 
                % calculations yield the same results every time for a given path
                for t=1:app.Length
                    app.Kalman(:,t+1) = Phi*app.Kalman(:,t); % Prior app.Kalman
                    P(:,:,t+1) = Phi*P(:,:,t)*Phi'+Q; % Prior covariance
                    K = P(:,:,t+1)*H'/(H*P(:,:,t+1)*H'+R); % Kalman gain
                    app.Kalman(:,t+1) = app.Kalman(:,t+1)+ ...
                        K*(app.Measurements(:,t)-H*app.Kalman(:,t+1));% Posterior app.Kalman
                    P(:,:,t+1) = (eye(dim)-K*H)*P(:,:,t+1);% Posterior covariance
                end
                
                % No track to create the path from
                if ~ishandle(app.HTrack)
                    return;
                end
                
                % Deletes the old Kalman fitting based on previous path
                if ishandle(app.HKalman)
                    delete(app.HKalman);
                    app.Error(:,2) = zeros(3,1);
                    app.Error(:,4) = zeros(3,1);
                    updateErrors(app);
                end
                
                % Update the plot if it exists, otherwise create one
                if ishandle(app.HMeasurements)
                    app.HMeasurements.XData = app.Measurements(1,:);
                    app.HMeasurements.YData = app.Measurements(2,:);
                else
                    hold(app.UIAxes);
                    app.HMeasurements = plot(app.UIAxes, app.Measurements(1,:), ...
                        app.Measurements(2,:), 'r.-');
                    hold(app.UIAxes);
                end
            end
        end

        % Button pushed function: KalmanfiltergreenButton
        function kalmanPushed(app, event)
            
            if (ishandle(app.HMeasurements))
                % Difference between trackpoints and Kalman filtered points
                err = sqrt(sum((app.Track-app.Kalman).^2));
                
                % Measurements' rms error
                app.Error(1,2) = sqrt(immse(app.Track(1:2,:), app.Kalman(1:2,:)));
                app.Error(1,4) = sqrt(immse(app.Track(3:4,:), app.Kalman(3:4,:)));
                % Measurements' max error
                app.Error(2,2) = max(max(err(1:2)));
                app.Error(2,4) = max(max(err(3:4)));
                % Measurements' median of error
                app.Error(3,2) = median(err(1:2));
                app.Error(3,4) = median(err(3:4));
                
                updateErrors(app);
                
                hold(app.UIAxes);
                app.HKalman = plot(app.UIAxes, app.Kalman(1,:),app.Kalman(2,:),'g.-');
                hold(app.UIAxes);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Kalman filter')
            xlabel(app.UIAxes, 'x-coordinate')
            ylabel(app.UIAxes, 'y-coordinate')
            app.LengthofTrackEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofTrackEditFieldLabel.Text = 'Length of Track';
            app.LengthofTrackEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofTrackEditField.Limits = [1 Inf];
            app.LengthofTrackEditField.Value = 40;
            app.GeneratetruetrackblueButton = uibutton(app.UIFigure, 'push');
            app.GeneratetruetrackblueButton.ButtonPushedFcn = createCallbackFcn(app, @truePushed, true);
            app.GeneratetruetrackblueButton.Text = 'Generate true track (blue)';
            app.GeneratemeasurementredButton = uibutton(app.UIFigure, 'push');
            app.GeneratemeasurementredButton.ButtonPushedFcn = createCallbackFcn(app, @measurementPushed, true);
            app.GeneratemeasurementredButton.Text = 'Generate measurement (red)';
            app.KalmanfiltergreenButton = uibutton(app.UIFigure, 'push');
            app.KalmanfiltergreenButton.ButtonPushedFcn = createCallbackFcn(app, @kalmanPushed, true);
            app.KalmanfiltergreenButton.Text = 'Kalman filter (green)';
            app.ClearfigureButton = uibutton(app.UIFigure, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @clearFigure, true);
            app.ClearfigureButton.Text = 'Clear figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Measurements'; 'Kalman'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {''; 'Measurements'; 'Kalman'};
            app.UITable_2.RowName = {};
            app.ErrorinvelocityLabel = uilabel(app.UIFigure);
            app.ErrorinvelocityLabel.Text = 'Error in velocity';
            app.ErrorinpositionLabel = uilabel(app.UIFigure);
            app.ErrorinpositionLabel.Text = 'Error in position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = P3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end